classdef Holography_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        xLabel_2                  matlab.ui.control.Label
        Booth65DHMReconstructionParametersLabel  matlab.ui.control.Label
        cmLabel                   matlab.ui.control.Label
        MagnificationSlider       matlab.ui.control.Slider
        MagnificationSliderLabel  matlab.ui.control.Label
        DistanceSlider            matlab.ui.control.Slider
        DistanceSliderLabel       matlab.ui.control.Label
        nmLabel                   matlab.ui.control.Label
        InputNoiseSlider          matlab.ui.control.Slider
        InputNoiseSliderLabel     matlab.ui.control.Label
        WavelengthSlider          matlab.ui.control.Slider
        WavelengthSliderLabel     matlab.ui.control.Label
        RunReconstructionButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("matlab_ihrri_toolbox");
            evalin("base", "reconstruct_camera_feed");
        end

        % Button pushed function: RunReconstructionButton
        function RunReconstructionButtonPushed(app, event)
            app.RunReconstructionButton.Text = "Reconstructing...";
            app.RunReconstructionButton.Enable = "off";

            % Magnification
            new_params.z_distance = app.DistanceSlider.Value * 1e-2; % 1-10 cm
            new_params.magnification = app.MagnificationSlider.Value; % 1-5 times
            new_params.lambda = app.WavelengthSlider.Value * 1e-9;
            new_params.muSparse = 10 ^ (-app.InputNoiseSlider.Value); % 1 to 6 % Input Noise 
            assignin("base", "new_params", new_params);
            evalin("base", "update_parameters_callback")
            evalin("base", "reconstruction_script");


            app.RunReconstructionButton.Text = "Run Reconstruction!!!";
            app.RunReconstructionButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunReconstructionButton = uibutton(app.UIFigure, 'push');
            app.RunReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @RunReconstructionButtonPushed, true);
            app.RunReconstructionButton.Text = 'Run Reconstruction!!!';
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [400 700];
            app.WavelengthSlider.Value = 400;
            app.InputNoiseSliderLabel = uilabel(app.UIFigure);
            app.InputNoiseSliderLabel.Text = 'Input Noise';
            app.InputNoiseSlider = uislider(app.UIFigure);
            app.InputNoiseSlider.Limits = [1 6];
            app.InputNoiseSlider.Value = 1;
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.DistanceSliderLabel = uilabel(app.UIFigure);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.UIFigure);
            app.DistanceSlider.Limits = [1 10];
            app.DistanceSlider.Value = 10;
            app.MagnificationSliderLabel = uilabel(app.UIFigure);
            app.MagnificationSliderLabel.Text = 'Magnification';
            app.MagnificationSlider = uislider(app.UIFigure);
            app.MagnificationSlider.Limits = [1 5];
            app.MagnificationSlider.Value = 1;
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.Booth65DHMReconstructionParametersLabel = uilabel(app.UIFigure);
            app.Booth65DHMReconstructionParametersLabel.Text = {'Booth 65'; 'DHM Reconstruction Parameters'};
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Holography_UI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end